classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        ImplementationTab          matlab.ui.container.Tab
        OutputPanel                matlab.ui.container.Panel
        Figure5                    matlab.ui.control.UIAxes
        Figure4                    matlab.ui.control.UIAxes
        Figure3                    matlab.ui.control.UIAxes
        InputPanel                 matlab.ui.container.Panel
        BrowseRGB                  matlab.ui.control.Button
        BrowseNIR                  matlab.ui.control.Button
        PathNIR                    matlab.ui.control.Label
        PathRGB                    matlab.ui.control.Label
        Figure1                    matlab.ui.control.UIAxes
        Figure2                    matlab.ui.control.UIAxes
        ControlCenterPanel         matlab.ui.container.Panel
        GammaLabel                 matlab.ui.control.Label
        BetaLabel                  matlab.ui.control.Label
        GammaSlider                matlab.ui.control.Slider
        GammaSliderLabel           matlab.ui.control.Label
        BetaSlider                 matlab.ui.control.Slider
        BetaSliderLabel            matlab.ui.control.Label
        JABParametersLabel         matlab.ui.control.Label
        Save                       matlab.ui.control.Button
        KnobLabel                  matlab.ui.control.Label
        AdaptiveButton             matlab.ui.control.Button
        FusionLabel                matlab.ui.control.Label
        PercentKnob                matlab.ui.control.Knob
        Process                    matlab.ui.control.Button
        ColorSpaceDropDown         matlab.ui.control.DropDown
        ColorSpaceDropDownLabel    matlab.ui.control.Label
        AboutTab                   matlab.ui.container.Tab
        DescriptionPanel           matlab.ui.container.Panel
        DistinguishedProfSosAgaiandLabel_7  matlab.ui.control.Label
        VersionHistoryPanel        matlab.ui.container.Panel
        DistinguishedProfSosAgaiandLabel_8  matlab.ui.control.Label
        PeoplePanel                matlab.ui.container.Panel
        DistinguishedProfSosAgaiandLabel_6  matlab.ui.control.Label
        DistinguishedProfSosAgaiandLabel_5  matlab.ui.control.Label
        Image_6                    matlab.ui.control.Image
        Image_5                    matlab.ui.control.Image
        DistinguishedProfSosAgaiandLabel_4  matlab.ui.control.Label
        DistinguishedProfSosAgaiandLabel_3  matlab.ui.control.Label
        DistinguishedProfSosAgaiandLabel_2  matlab.ui.control.Label
        DistinguishedProfSosAgaiandLabel  matlab.ui.control.Label
        Image_4                    matlab.ui.control.Image
        Image_3                    matlab.ui.control.Image
        Image_2                    matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        MultispectralImageryLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        NIR
        RGB
        EnhancedNIR
        EnhancedRGB
        EnhancedGray
        Result
        Fused
        img
        Matrix
        JAB
        
    end  
    
    methods (Access = public)
        function newimg = NormalRange(app, Jmin, Jmax, frac)
            im = app.EnhancedNIR;
            if ~isa(im, 'double' )
                im = double(im);
            end
            Imax = max(im(:));
            Imin = min(im(:));
            if Imin < 0
                im = (im - Imin) / (abs(Imin) + Imax);
                Imin = 0;
            end
            newimg = im - Imin;
            newimg = newimg ./ max(newimg(:)) * (Jmax - Jmin);
            newimg = newimg + Jmin;
            if frac == 1
                newimg = uint8(newimg);
            end
        end

        function [JzAzBz,Jz,Az,Bz,XYZ] = rgb2jab(app)
            I = app.RGB;
            tf = isa(I,'double');
            if tf ~= 1
                I = double(I);
            end
%             b = 1.00; 
%             g = 0.66; 
            b = app.BetaSlider.Value;
            g = app.GammaSlider.Value;
            d = -0.56;
            n = 2610/2^14; p = (1.7)*2523/2^5;  
            c1 = 3424/2^12; c2 = 2413/2^7; c3 = 2392/2^7;
            MM1 = [0.5767309 0.1855540 0.1881852; 0.2973769 0.6273491 0.0752741; 0.0270343 0.0706872 0.9911085];
            MM2 = [0.41478972 0.579999 0.0146480;-0.2015100 1.120649 0.0531008;-0.0166008 0.264800 0.6684799];
            MM3 = [0.5 0.5 0; 3.524000  -4.066708 +0.542708; 0.199076 +1.096799  -1.295875];
            %% Convert
            app.Matrix = MM1;
            app.img    = I;
            XYZ = mtime(app);
            X = XYZ(:,:,1);
            Y = XYZ(:,:,2);
            Z = XYZ(:,:,3);
            XYZ(:,:,1) = b .* X - (b - 1) .* Z;
            XYZ(:,:,2) = g .* Y - (g - 1) .* X;
            app.Matrix = MM2;
            app.img    = XYZ;
            LMS = mtime(app);
            l   = ((c1+c2*(LMS(:,:,1)./10000).^n) ./ (1+c3*(LMS(:,:,1)./10000).^n)).^p;
            m   = ((c1+c2*(LMS(:,:,2)./10000).^n) ./ (1+c3*(LMS(:,:,2)./10000).^n)).^p;
            s   = ((c1+c2*(LMS(:,:,3)./10000).^n) ./ (1+c3*(LMS(:,:,3)./10000).^n)).^p;
            lms = cat(3,l,m,s);
            app.Matrix = MM3;
            app.img    = lms;
            [~,Iz,Az,Bz] = mtime(app);
            Jz = (1+d)*Iz./(1+d*Iz)-1.6295499532821566e-11;
            JzAzBz = cat(3,Jz,Az,Bz);
        end

        function [RGB,XYZ_I] = jab2rgb(app)
            JzAzBz = app.JAB;
            b = app.BetaSlider.Value;
            g = app.GammaSlider.Value;
%             b = 1.00; 
%             g = 0.66; 
            d = -0.56;
            n = 2610/2^14; p = (1.7)*2523/2^5;  
            c1 = 3424/2^12; c2 = 2413/2^7; c3 = 2392/2^7;
            MM1 = [0.5767309 0.1855540 0.1881852; 0.2973769 0.6273491 0.0752741; 0.0270343 0.0706872 0.9911085];
            MM2 = [0.41478972 0.579999 0.0146480;-0.2015100 1.120649 0.0531008;-0.0166008 0.264800 0.6684799];
            MM3 = [0.5 0.5 0; 3.524000  -4.066708 +0.542708; 0.199076 +1.096799  -1.295875];
            %% Invert
            JzAzBz_I = JzAzBz;
            JzAzBz_I(:,:,1) = JzAzBz(:,:,1)+1.6295499532821566e-11;
            Iz_I = JzAzBz_I(:,:,1)./(1+d-d*JzAzBz_I(:,:,1));
            IzAzBz_I = cat(3,Iz_I,JzAzBz_I(:,:,2),JzAzBz_I(:,:,3));
            app.img = IzAzBz_I;
            app.Matrix = inv(MM3);
            [lms_I,~,~,~] = mtime(app);
            LMS_I(:,:,1) = 10000*((c1-lms_I(:,:,1).^(1/p))./(c3*lms_I(:,:,1).^(1/p)-c2)).^(1/n);
            LMS_I(:,:,2) = 10000*((c1-lms_I(:,:,2).^(1/p))./(c3*lms_I(:,:,2).^(1/p)-c2)).^(1/n);
            LMS_I(:,:,3) = 10000*((c1-lms_I(:,:,3).^(1/p))./(c3*lms_I(:,:,3).^(1/p)-c2)).^(1/n);
            app.img = LMS_I;
            app.Matrix = inv(MM2);            
            [XYZ_I,X_I,Y_I,Z_I] = mtime(app);
            XYZ_I(:,:,1) = (X_I + (b - 1) .* Z_I) ./ b;
            XYZ_I(:,:,2) = (Y_I + (g - 1) .* X_I) ./ g;
            app.img = XYZ_I;
            app.Matrix = inv(MM1);             
            RGB = mtime(app);
            RGB = uint8(RGB);
        end

        function [J,U,V,W] = mtime(app)
            I = app.img;
            M = app.Matrix;
            tf = isa(I,'uint8');
            if tf == 1
                I = double(I);
            end
            A = I(:,:,1);
            B = I(:,:,2);
            C = I(:,:,3);
            U = (M(1,1) * A) + (M(1,2) * B) + (M(1,3) * C);
            V = (M(2,1) * A) + (M(2,2) * B) + (M(2,3) * C);
            W = (M(3,1) * A) + (M(3,2) * B) + (M(3,3) * C);
            J = cat(3,U,V,W);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseRGB
        function BrowseRGBPushed(app, event)
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
            app.PathRGB.Text = [path,file];
            app.RGB = imread([path,file]);
            imshow(app.RGB,'Parent',app.Figure1)
            app.BrowseNIR.Enable = "on";
        end

        % Button pushed function: BrowseNIR
        function BrowseNIRButtonPushed(app, event)
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
            app.PathNIR.Text = [path,file];
            app.NIR = imread([path,file]);
            imshow(app.NIR,'Parent',app.Figure2)
            app.Process.Enable = "on";
        end

        % Button pushed function: Process
        function ProcessButtonPushed(app, event)
            G = rgb2gray(app.RGB);
            app.EnhancedGray = adapthisteq(G);
            app.EnhancedNIR = adapthisteq(app.NIR);
            w = double(app.EnhancedGray) / 255;
            app.EnhancedNIR = uint8(w .* double(app.EnhancedGray) + (1 - w) .* double(app.EnhancedNIR));
            imshow(app.EnhancedNIR,'Parent',app.Figure4)
            switch app.ColorSpaceDropDown.Value
                case "HSV"
                    HSV = rgb2hsv(app.RGB);
                    HSV(:,:,3) = double(app.EnhancedNIR)/255;
                    newimg = hsv2rgb(HSV);
                    app.EnhancedRGB = uint8(newimg*255);
                case "NTSC"
                    YIQ = rgb2ntsc(app.RGB);
                    Y = YIQ(:,:,1);
                    Imin = min(Y(:));
                    Imax = max(Y(:));
                    y = NormalRange(app,Imin,Imax,0);
                    YIQ(:,:,1) = y;
                    newimg = ntsc2rgb(YIQ);
                    app.EnhancedRGB = uint8(newimg*255);
                case "JAB"
                    app.JAB = rgb2jab(app);
                    J = app.JAB(:,:,1);
                    Imin = min(J(:));
                    Imax = max(J(:));
                    y = NormalRange(app,Imin,Imax,0);
                    app.JAB(:,:,1) = y;
                    app.EnhancedRGB = jab2rgb(app);
            end
            imshow(app.EnhancedRGB,'Parent',app.Figure3)
            app.PercentKnob.Enable = "on";
            app.AdaptiveButton.Enable = "on";
        end

        % Value changed function: PercentKnob
        function PercentKnobValueChanged(app, event)
            w = app.PercentKnob.Value;
            app.KnobLabel.Text = [num2str(w,'%0.2f'),'%'];
            w = double(w) / 100;
            app.Fused = uint8(w .* double(app.RGB) + (1 - w) .* double(app.EnhancedRGB));
            imshow(app.Fused,'Parent',app.Figure5)
            app.Save.Enable = "on";
        end

        % Button pushed function: AdaptiveButton
        function AdaptiveButtonPushed(app, event)
            w = double(app.RGB)/255;
            app.Fused = uint8(w .* double(app.RGB) + (1 - w) .* double(app.EnhancedRGB));
            imshow(app.Fused,'Parent',app.Figure5)
            app.PercentKnob.Value = 0;
            app.KnobLabel.Text = "Auto";
            app.Save.Enable = "on";
        end

        % Button pushed function: Save
        function SavePushed(app, event)
            [file,path] = uiputfile('Results.png');
            imwrite(app.Fused, [path,file]);
        end

        % Value changed function: ColorSpaceDropDown
        function ColorSpaceDropDownValueChanged(app, event)
            value = app.ColorSpaceDropDown.Value;
            if value == "JAB"
                
            end
        end

        % Value changed function: BetaSlider
        function BetaSliderValueChanged(app, event)

        end

        % Value changing function: BetaSlider
        function BetaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.BetaLabel.Text = num2str(changingValue,'%0.4f');
        end

        % Value changing function: GammaSlider
        function GammaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.GammaLabel.Text = num2str(changingValue,'%0.4f');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MultispectralImageryLabel = uilabel(app.UIFigure);
            app.MultispectralImageryLabel.Text = 'Multispectral Imagery';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImplementationTab = uitab(app.TabGroup);
            app.ImplementationTab.Title = 'Implementation';
            app.ControlCenterPanel = uipanel(app.ImplementationTab);
            app.ControlCenterPanel.Title = 'Control Center';
            app.ColorSpaceDropDownLabel = uilabel(app.ControlCenterPanel);
            app.ColorSpaceDropDownLabel.Text = 'Color-Space';
            app.ColorSpaceDropDown = uidropdown(app.ControlCenterPanel);
            app.ColorSpaceDropDown.Items = {'HSV', 'NTSC', 'JAB'};
            app.ColorSpaceDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorSpaceDropDownValueChanged, true);
            app.ColorSpaceDropDown.Value = 'HSV';
            app.Process = uibutton(app.ControlCenterPanel, 'push');
            app.Process.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.Process.Enable = 'off';
            app.Process.Text = 'Process';
            app.PercentKnob = uiknob(app.ControlCenterPanel, 'continuous');
            app.PercentKnob.ValueChangedFcn = createCallbackFcn(app, @PercentKnobValueChanged, true);
            app.PercentKnob.Enable = 'off';
            app.FusionLabel = uilabel(app.ControlCenterPanel);
            app.FusionLabel.Text = 'Fusion';
            app.AdaptiveButton = uibutton(app.ControlCenterPanel, 'push');
            app.AdaptiveButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptiveButtonPushed, true);
            app.AdaptiveButton.Enable = 'off';
            app.AdaptiveButton.Text = 'Adaptive';
            app.KnobLabel = uilabel(app.ControlCenterPanel);
            app.KnobLabel.Text = '0%';
            app.Save = uibutton(app.ControlCenterPanel, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SavePushed, true);
            app.Save.Enable = 'off';
            app.Save.Text = 'Save a resulting image';
            app.JABParametersLabel = uilabel(app.ControlCenterPanel);
            app.JABParametersLabel.Text = 'JAB Parameters:';
            app.BetaSliderLabel = uilabel(app.ControlCenterPanel);
            app.BetaSliderLabel.Text = 'Beta';
            app.BetaSlider = uislider(app.ControlCenterPanel);
            app.BetaSlider.Limits = [-2 2];
            app.BetaSlider.ValueChangedFcn = createCallbackFcn(app, @BetaSliderValueChanged, true);
            app.BetaSlider.ValueChangingFcn = createCallbackFcn(app, @BetaSliderValueChanging, true);
            app.BetaSlider.Value = 1;
            app.GammaSliderLabel = uilabel(app.ControlCenterPanel);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.ControlCenterPanel);
            app.GammaSlider.Limits = [-2 2];
            app.GammaSlider.ValueChangingFcn = createCallbackFcn(app, @GammaSliderValueChanging, true);
            app.GammaSlider.Value = 0.66;
            app.BetaLabel = uilabel(app.ControlCenterPanel);
            app.BetaLabel.Text = '1.0000';
            app.GammaLabel = uilabel(app.ControlCenterPanel);
            app.GammaLabel.Text = '0.6600';
            app.InputPanel = uipanel(app.ImplementationTab);
            app.InputPanel.Title = 'Input';
            app.Figure2 = uiaxes(app.InputPanel);
            title(app.Figure2, 'Input Infrared image')
            app.Figure2.XTick = [];
            app.Figure2.YTick = [];
            app.Figure2.YTickLabel = '';
            app.Figure1 = uiaxes(app.InputPanel);
            title(app.Figure1, 'Input Visible image')
            app.Figure1.XTick = [];
            app.Figure1.YTick = [];
            app.Figure1.YTickLabel = '';
            app.PathRGB = uilabel(app.InputPanel);
            app.PathRGB.Text = 'File Path:';
            app.PathNIR = uilabel(app.InputPanel);
            app.PathNIR.Text = 'File Path:';
            app.BrowseNIR = uibutton(app.InputPanel, 'push');
            app.BrowseNIR.ButtonPushedFcn = createCallbackFcn(app, @BrowseNIRButtonPushed, true);
            app.BrowseNIR.Enable = 'off';
            app.BrowseNIR.Text = 'Browse';
            app.BrowseRGB = uibutton(app.InputPanel, 'push');
            app.BrowseRGB.ButtonPushedFcn = createCallbackFcn(app, @BrowseRGBPushed, true);
            app.BrowseRGB.Text = 'Browse';
            app.OutputPanel = uipanel(app.ImplementationTab);
            app.OutputPanel.Title = 'Output';
            app.Figure3 = uiaxes(app.OutputPanel);
            title(app.Figure3, 'Visible Image with Enhanced Infrared Component')
            app.Figure3.XTick = [];
            app.Figure3.YTick = [];
            app.Figure3.YTickLabel = '';
            app.Figure4 = uiaxes(app.OutputPanel);
            title(app.Figure4, 'Enhanced Infrared Image')
            app.Figure4.XTick = [];
            app.Figure4.YTick = [];
            app.Figure4.YTickLabel = '';
            app.Figure5 = uiaxes(app.OutputPanel);
            title(app.Figure5, 'Resulting Image')
            app.Figure5.XTick = [];
            app.Figure5.YTick = [];
            app.Figure5.YTickLabel = '';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.PeoplePanel = uipanel(app.AboutTab);
            app.PeoplePanel.Title = 'People';
            app.Image = uiimage(app.PeoplePanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'People01.jpg');
            app.Image_2 = uiimage(app.PeoplePanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'People02.jpg');
            app.Image_3 = uiimage(app.PeoplePanel);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'People03.jpg');
            app.Image_4 = uiimage(app.PeoplePanel);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'People04.jpg');
            app.DistinguishedProfSosAgaiandLabel = uilabel(app.PeoplePanel);
            app.DistinguishedProfSosAgaiandLabel.Text = {'Prof. Sos Agaian'; 'Distinguished Professor of Computer Science'; 'Foreign Member of Armenian National Academy'; 'IEEE Fellow, SPIE Fellow, AAAS Fellow, IS&T Fellow'; 'Computer Science Department,The College of Staten Island, and'; 'Graduate Center, City University of New York (CUNY)'; 'E-mail: sos.agaian@csi.cuny.edu'};
            app.DistinguishedProfSosAgaiandLabel_2 = uilabel(app.PeoplePanel);
            app.DistinguishedProfSosAgaiandLabel_2.Text = {'Thaweesak Trongtirakul, D.Eng.'; 'Electrical Engineering, Industrual Education, '; 'Rajamangala University of Technology Phra Nakhon (RMUTP)'; 'SPIE Member'; 'E-mail: thaweesak.tr@rmutp.ac.th'};
            app.DistinguishedProfSosAgaiandLabel_3 = uilabel(app.PeoplePanel);
            app.DistinguishedProfSosAgaiandLabel_3.Text = {'Krittayot Kuisorn'; 'Electrical Engineering, Industrual Education, '; 'Rajamangala University of Technology Phra Nakhon (RMUTP)'; 'Project Co-ordinator'; 'E-mail: pornthep-s@rmutp.ac.th'; 'Tel: +66917037988'};
            app.DistinguishedProfSosAgaiandLabel_4 = uilabel(app.PeoplePanel);
            app.DistinguishedProfSosAgaiandLabel_4.Text = {'Peerapol Somsri'; 'Electrical Engineering, Industrual Education, '; 'Rajamangala University of Technology Phra Nakhon (RMUTP)'; 'Project Member'; 'E-mail: peerapol-s@rmutp.ac.th'; 'Tel: +66952193211'};
            app.Image_5 = uiimage(app.PeoplePanel);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'People05.jpg');
            app.Image_6 = uiimage(app.PeoplePanel);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'People06.jpg');
            app.DistinguishedProfSosAgaiandLabel_5 = uilabel(app.PeoplePanel);
            app.DistinguishedProfSosAgaiandLabel_5.Text = {'Charinthon Thapthimthong'; 'Electrical Engineering, Industrual Education, '; 'Rajamangala University of Technology Phra Nakhon (RMUTP)'; 'Project Member'; 'E-mail: charinthon-t@rmutp.ac.th'; 'Tel: +66886588529'};
            app.DistinguishedProfSosAgaiandLabel_6 = uilabel(app.PeoplePanel);
            app.DistinguishedProfSosAgaiandLabel_6.Text = {'Rangsan Langka'; 'Electrical Engineering, Industrual Education, '; 'Rajamangala University of Technology Phra Nakhon (RMUTP)'; 'Project Member'; 'E-mail: rangsan-l@rmutp.ac.th'; 'Tel: +66645654240'};
            app.VersionHistoryPanel = uipanel(app.AboutTab);
            app.VersionHistoryPanel.Title = 'Version History';
            app.DistinguishedProfSosAgaiandLabel_8 = uilabel(app.VersionHistoryPanel);
            app.DistinguishedProfSosAgaiandLabel_8.Text = {'Version 3.0.0'; '- Add a new enhancement technique on a synthetic infrared imaging component. '; '- Bug fixes and performance improvements.'; ''; 'Version 2.0.0'; '- Two additional color-spaces added, bug fixes and performance improvements.'; ''; 'Version 1.0.0'; '- Lunch a new framework for multispectral imagery.'};
            app.DescriptionPanel = uipanel(app.AboutTab);
            app.DescriptionPanel.Title = 'Description';
            app.DistinguishedProfSosAgaiandLabel_7 = uilabel(app.DescriptionPanel);
            app.DistinguishedProfSosAgaiandLabel_7.Text = {'Visible images illustrate chominance information with high-spatial resolution and definition in a manner consistent with the human visual system. However, color images suffer from nighttime conditions due to insufficient luminance and reflectance. On the other hand, infrared images deliver disthingish details based on the radiation difference, which operates well in all-weather and all-day/night conditions. Therefore, it is reasonable to integrate these two types of images, which can fuse the advantages of thermal radiation-dependent information in infrared imagery and chominance details in visible imagery.'; ''; 'In this work, we comprehensively build the image visualization method for visible and infrared images with a Graphic User Interface (GUI) application. Also, we provide the selection of a color-space in fusing thermal radiation-dependent information into chominance color imaging components.'; ''; 'Finally, we hope you enjoy working on our multispectral-imagery illustrative application.'; ''; 'Best,'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end